classdef ExponentaApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentaAppUIFigure  matlab.ui.Figure
        Exponentaru           matlab.ui.control.Button
        Docs                  matlab.ui.control.Button
        MATLABInRussia        matlab.ui.control.Button
        Hub                   matlab.ui.control.Button
        Ask                   matlab.ui.control.Button
        Matlabru              matlab.ui.control.Button
        Help                  matlab.ui.control.Button
        Update                matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        Button                matlab.ui.control.Button
    end

    
    properties (Access = private)
        Updater % ToolboxUpdater
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Updater = ExponentaUpdater();
            if ~app.Updater.ext.isfavs()
                app.Updater.ext.addfav('Документация', 'rusdoc', 'icon-mw-documentation.png');
                app.Updater.ext.addfav('Сообщество', 'hub', 'icon-mat-people.png');
                app.Updater.ext.addfav('Exponenta App', 'ExponentaApp', 'icon-my-etmc-exponenta.png');
            end
        end

        % Button pushed function: Exponentaru
        function ExponentaruPushed(app, event)
            exponenta
        end

        % Button pushed function: Docs
        function DocsPushed(app, event)
            rusdoc
        end

        % Button pushed function: MATLABInRussia
        function MATLABInRussiaPushed(app, event)
            matlabinrussia
        end

        % Button pushed function: Hub
        function HubButtonPushed(app, event)
            hub
        end

        % Button pushed function: Ask
        function AskButtonPushed(app, event)
            ask
        end

        % Button pushed function: Matlabru
        function MatlabruButtonPushed(app, event)
            matlabru
        end

        % Button pushed function: Help
        function HelpButtonPushed(app, event)
            ExponentaDoc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentaAppUIFigure = uifigure('Visible', 'off');
            app.ExponentaAppUIFigure.Name = 'Exponenta App';
            app.Exponentaru = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Exponentaru.ButtonPushedFcn = createCallbackFcn(app, @ExponentaruPushed, true);
            app.Exponentaru.Icon = 'icon-my-etmc-exponenta.png';
            app.Exponentaru.Text = 'exponenta.ru';
            app.Docs = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Docs.ButtonPushedFcn = createCallbackFcn(app, @DocsPushed, true);
            app.Docs.Icon = 'icon-mw-documentation.png';
            app.Docs.Text = 'Документация на русском';
            app.MATLABInRussia = uibutton(app.ExponentaAppUIFigure, 'push');
            app.MATLABInRussia.ButtonPushedFcn = createCallbackFcn(app, @MATLABInRussiaPushed, true);
            app.MATLABInRussia.Icon = 'icon-fa-youtube-play.png';
            app.MATLABInRussia.Text = 'Видео MATLABInRussia';
            app.Hub = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Hub.ButtonPushedFcn = createCallbackFcn(app, @HubButtonPushed, true);
            app.Hub.Icon = 'icon-mat-people.png';
            app.Hub.Text = 'Сообщество';
            app.Ask = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Ask.ButtonPushedFcn = createCallbackFcn(app, @AskButtonPushed, true);
            app.Ask.Icon = 'icon-mw-answers.png';
            app.Ask.Text = 'Спросить';
            app.Matlabru = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Matlabru.ButtonPushedFcn = createCallbackFcn(app, @MatlabruButtonPushed, true);
            app.Matlabru.Icon = 'icon-mw-matlab.png';
            app.Matlabru.Text = 'matlab.ru';
            app.Help = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Help.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.Help.Icon = 'icon-mat-help-outline.png';
            app.Help.Tooltip = {'Справка'};
            app.Help.Text = '';
            app.Update = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Update.Icon = 'icon-my-update-daa520.png';
            app.Update.Tooltip = {'Обновить'};
            app.Update.Text = '';
            app.Label = uilabel(app.ExponentaAppUIFigure);
            app.Label.Text = 'Доступна новая версия';
            app.Button = uibutton(app.ExponentaAppUIFigure, 'push');
            app.Button.Text = 'Запросить пробную версию';
            % Show the figure after all components are created
            app.ExponentaAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExponentaApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentaAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentaAppUIFigure)
        end
    end
end