classdef ExponentaEditorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExponentaEditorUIFigure  matlab.ui.Figure
        NotificationsTable       matlab.ui.control.Table
        MessageTextAreaLabel     matlab.ui.control.Label
        Message                  matlab.ui.control.TextArea
        ThemeDropDownLabel       matlab.ui.control.Label
        Theme                    matlab.ui.control.DropDown
        Checked                  matlab.ui.control.CheckBox
        DueDateDatePickerLabel   matlab.ui.control.Label
        DueDate                  matlab.ui.control.DatePicker
        Add                      matlab.ui.control.Button
        Delete                   matlab.ui.control.Button
        ActionsTable             matlab.ui.control.Table
        ActionsLabel             matlab.ui.control.Label
        AddAction                matlab.ui.control.Button
        DeleteAction             matlab.ui.control.Button
        Load                     matlab.ui.control.Button
        Save                     matlab.ui.control.Button
        Show                     matlab.ui.control.Button
        Close                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Editor % ExponentaEditor
        Notifications % Notifications Reactive Table
        Notification
        Actions
    end
    
    methods (Access = private)
        
        function data = ReadActions(app)
            data = app.Notifications.get('actions');
            if ~ischar(data{1})
                data = data{1};
            end
            if isempty(data)
                data = cell(0, 2);
            end
            data = cell2table(data, 'VariableNames', {'name' 'fcn'});
        end
        
        function WriteActions(app, data)
            if ~isempty(data)
                data = table2cell(data);
            else
                data = [];
            end
            app.Notifications.set('actions', {data});
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LoadButtonPushed();
        end

        % Cell selection callback: NotificationsTable
        function NotificationsTableCellSelection(app, event)
            app.Notifications.select(event);
            app.Actions = UI.ReactiveTable([], @app.ReadActions, @app.WriteActions);
            app.Actions.bind(app.ActionsTable);
        end

        % Value changed function: Checked, DueDate, Message, Theme
        function MessageValueChanged(app, event)
            app.Notifications.updateVar(event);
        end

        % Display data changed function: NotificationsTable
        function NotificationsTableDisplayDataChanged(app, event)
            app.Notifications.update(event);
        end

        % Button pushed function: Add
        function AddButtonPushed(app, event)
            code = app.Editor.genCode();
            duedate = datetime + calmonths(1);
            app.Notifications.newRow('theme', 'info', 'code', code, 'duedate', duedate, 'actions', {[]});
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            app.Notifications.deleteRow();
        end

        % Callback function
        function SaveActionsButtonPushed(app, event)
            app.Actions.update();
        end

        % Cell selection callback: ActionsTable
        function ActionsTableCellSelection(app, event)
            app.Actions.select(event);
        end

        % Button pushed function: AddAction
        function AddActionButtonPushed(app, event)
            app.Actions.newRow('name', {'Actions'}, 'fcn', {'@(~,~)web("https://exponenta.ru")'});
        end

        % Cell edit callback: ActionsTable
        function ActionsTableCellEdit(app, event)
            app.Actions.update(event);
        end

        % Button pushed function: DeleteAction
        function DeleteActionButtonPushed(app, event)
            app.Actions.deleteRow();
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            app.Editor = ExponentaEditor;
            app.Notifications = UI.ReactiveTable(app.Editor, 'Data', 'Data');
            app.Notifications.select([1 1]);
            app.Notifications.bind(app.NotificationsTable);
            app.Notifications.bindVar('message', app.Message);
            app.Notifications.bindVar('theme', app.Theme);
            app.Notifications.bindVar('checked', app.Checked);
            app.Notifications.bindVar('duedate', app.DueDate);
            app.NotificationsTableCellSelection([1 1]);
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            if ~isempty(app.Editor)
                app.Editor.save();
            end
        end

        % Button pushed function: Show
        function ShowPushed(app, event)
            app.CloseButtonPushed();
            data = app.Notifications.getRow();
            app.Notification = app.Editor.showNotification(app.ExponentaEditorUIFigure, data);
        end

        % Button pushed function: Close
        function CloseButtonPushed(app, event)
            delete(app.Notification);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExponentaEditorUIFigure = uifigure('Visible', 'off');
            app.ExponentaEditorUIFigure.Name = 'Exponenta Editor';
            app.NotificationsTable = uitable(app.ExponentaEditorUIFigure);
            app.NotificationsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''};
            app.NotificationsTable.RowName = {};
            app.NotificationsTable.ColumnEditable = [false true true true true];
            app.NotificationsTable.CellSelectionCallback = createCallbackFcn(app, @NotificationsTableCellSelection, true);
            app.NotificationsTable.DisplayDataChangedFcn = createCallbackFcn(app, @NotificationsTableDisplayDataChanged, true);
            app.MessageTextAreaLabel = uilabel(app.ExponentaEditorUIFigure);
            app.MessageTextAreaLabel.Text = 'Message';
            app.Message = uitextarea(app.ExponentaEditorUIFigure);
            app.Message.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.ThemeDropDownLabel = uilabel(app.ExponentaEditorUIFigure);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.Theme = uidropdown(app.ExponentaEditorUIFigure);
            app.Theme.Items = {'dark', 'light', 'success', 'info', 'warning', 'danger'};
            app.Theme.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Theme.Value = 'dark';
            app.Checked = uicheckbox(app.ExponentaEditorUIFigure);
            app.Checked.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Checked.Text = 'Checked';
            app.DueDateDatePickerLabel = uilabel(app.ExponentaEditorUIFigure);
            app.DueDateDatePickerLabel.Text = 'Due Date';
            app.DueDate = uidatepicker(app.ExponentaEditorUIFigure);
            app.DueDate.ValueChangedFcn = createCallbackFcn(app, @MessageValueChanged, true);
            app.Add = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Add.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.Add.Text = 'Add';
            app.Delete = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete';
            app.ActionsTable = uitable(app.ExponentaEditorUIFigure);
            app.ActionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.ActionsTable.RowName = {};
            app.ActionsTable.ColumnEditable = [true true false false];
            app.ActionsTable.CellEditCallback = createCallbackFcn(app, @ActionsTableCellEdit, true);
            app.ActionsTable.CellSelectionCallback = createCallbackFcn(app, @ActionsTableCellSelection, true);
            app.ActionsLabel = uilabel(app.ExponentaEditorUIFigure);
            app.ActionsLabel.Text = 'Actions';
            app.AddAction = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.AddAction.ButtonPushedFcn = createCallbackFcn(app, @AddActionButtonPushed, true);
            app.AddAction.Text = 'Add';
            app.DeleteAction = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.DeleteAction.ButtonPushedFcn = createCallbackFcn(app, @DeleteActionButtonPushed, true);
            app.DeleteAction.Text = 'Delete';
            app.Load = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Save = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Text = 'Save';
            app.Show = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Show.ButtonPushedFcn = createCallbackFcn(app, @ShowPushed, true);
            app.Show.Text = 'Show';
            app.Close = uibutton(app.ExponentaEditorUIFigure, 'push');
            app.Close.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.Close.Text = 'Close';
            % Show the figure after all components are created
            app.ExponentaEditorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExponentaEditorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExponentaEditorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExponentaEditorUIFigure)
        end
    end
end